classdef termproject_app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        degLabel_4                  matlab.ui.control.Label
        degLabel_3                  matlab.ui.control.Label
        degLabel_2                  matlab.ui.control.Label
        degLabel                    matlab.ui.control.Label
        minelevationEditField       matlab.ui.control.NumericEditField
        minelevationEditFieldLabel  matlab.ui.control.Label
        initialEpoch                matlab.ui.control.Label
        finalEpoch                  matlab.ui.control.Label
        HEditField                  matlab.ui.control.NumericEditField
        HEditFieldLabel             matlab.ui.control.Label
        latEditField                matlab.ui.control.NumericEditField
        latEditFieldLabel           matlab.ui.control.Label
        IonEditField                matlab.ui.control.NumericEditField
        IonEditFieldLabel           matlab.ui.control.Label
        LoadButton                  matlab.ui.control.Button
        epochEditField              matlab.ui.control.EditField
        epochEditFieldLabel         matlab.ui.control.Label
        SYSDropDown                 matlab.ui.control.DropDown
        SYSDropDownLabel            matlab.ui.control.Label
        Slider                      matlab.ui.control.Slider
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global date;
            date = datetime('now');
            app.epochEditField.Value = string(date);
            nextdate = date + 1;

            app.initialEpoch.Text = string(date.Year) + '-' + string(date.Month) + '-' + string(date.Day);
            app.finalEpoch.Text =string(nextdate.Year) + '-' + string(nextdate.Month) + '-' + string(nextdate.Day);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            load('nav.mat');
             
            value = app.SYSDropDown.Value;

            if strcmp(value, "GPS") == 1
                data = nav.GPS;
            elseif strcmp(value, "QZSS") == 1
                data = nav.QZSS;
            else
                data = nav.BDS;
            end
            
            
            % data : a, e, i, omega, M0, toc, OMEGA (at t = 0)
            % from data to calculate satteite position, velocity
            
            
            %PQR -> XYZ
            r_xyz = inv(PQW2ECI(data.omega, data.i, data.OMEGA)) * solveRangeInPerifocalFrame(data.a, data.e, data.omega); 
            v_xyz = inv(PQW2ECI(data.omega, data.i, data.OMEGA)) * solveVelocityInPerifocalFrame(data.a, data.e, data.omega);
            

            %DCM = ECI2ECEF_DCM(date);
            
            %ECI to ENU
            lon = app.IonEditField.Value;
            lat = app.latEditField.Value;
            
            ECI2ENU = [1 0 0; 0 cosd(90 - lon) sind(90 - lon); 0 -sind(90 - lon) cosd(90 - lon)] * [ cosd(90 + lat) sind(90 + lat) 0; -sind(90 + lat) cosd(90 + lat) 0; 0 0 1];
            r_enu = ECI2ENU * r_xyz;
            v_enu = ECI2ENU * v_xyz;

            %azimuth angle calculator
            %az = azimuth(ENU);

            
            %elevation
            el_mask = app.minelevationEditField.Value;
            %el = elevation(ENU, el_mask);
            
            geoplot(app.UIAxes2,lon, lat);


        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            
            % epoch field change also

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Slider = uislider(app.UIFigure);
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {''};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.SYSDropDownLabel = uilabel(app.UIFigure);
            app.SYSDropDownLabel.Text = 'SYS';
            app.SYSDropDown = uidropdown(app.UIFigure);
            app.SYSDropDown.Items = {'GPS', 'QZSS', 'BDS'};
            app.SYSDropDown.Value = 'GPS';
            app.epochEditFieldLabel = uilabel(app.UIFigure);
            app.epochEditFieldLabel.Text = 'epoch';
            app.epochEditField = uieditfield(app.UIFigure, 'text');
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.IonEditFieldLabel = uilabel(app.UIFigure);
            app.IonEditFieldLabel.Text = 'Ion';
            app.IonEditField = uieditfield(app.UIFigure, 'numeric');
            app.IonEditField.Value = 127;
            app.latEditFieldLabel = uilabel(app.UIFigure);
            app.latEditFieldLabel.Text = 'lat';
            app.latEditField = uieditfield(app.UIFigure, 'numeric');
            app.latEditField.Value = 37;
            app.HEditFieldLabel = uilabel(app.UIFigure);
            app.HEditFieldLabel.Text = 'H';
            app.HEditField = uieditfield(app.UIFigure, 'numeric');
            app.HEditField.Value = 1;
            app.finalEpoch = uilabel(app.UIFigure);
            app.finalEpoch.Text = ' ';
            app.initialEpoch = uilabel(app.UIFigure);
            app.initialEpoch.Text = ' ';
            app.minelevationEditFieldLabel = uilabel(app.UIFigure);
            app.minelevationEditFieldLabel.Text = 'min elevation';
            app.minelevationEditField = uieditfield(app.UIFigure, 'numeric');
            app.degLabel = uilabel(app.UIFigure);
            app.degLabel.Text = 'deg';
            app.degLabel_2 = uilabel(app.UIFigure);
            app.degLabel_2.Text = 'deg';
            app.degLabel_3 = uilabel(app.UIFigure);
            app.degLabel_3.Text = 'deg';
            app.degLabel_4 = uilabel(app.UIFigure);
            app.degLabel_4.Text = 'deg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = termproject_app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end